classdef mcl_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplesPicker                   matlab.ui.Figure
        ApplespickerLabel              matlab.ui.control.Label
        AppleNumberSpinner             matlab.ui.control.Spinner
        TargetDropDown                 matlab.ui.control.DropDown
        OutlierDetectionLabel_7        matlab.ui.control.Label
        OutlierPercentageSpinner       matlab.ui.control.Spinner
        OutlierDetectionLabel_6        matlab.ui.control.Label
        OutlierDetectionLabel_5        matlab.ui.control.Label
        GlobalTrackingSwitch           matlab.ui.control.Switch
        StopButton                     matlab.ui.control.Button
        OutlierSwitch_d                matlab.ui.control.Switch
        OutlierDetectionLabel_2        matlab.ui.control.Label
        OutlierSpinner_d               matlab.ui.control.Spinner
        ResamplingLabel                matlab.ui.control.Label
        Spinner_R2_t                   matlab.ui.control.Spinner
        xySpinnerLabel_3               matlab.ui.control.Label
        Error_theta_Field_d2           matlab.ui.control.NumericEditField
        Error_theta_Label_3            matlab.ui.control.Label
        Error_y_Field_d2               matlab.ui.control.NumericEditField
        Error_y_Label_3                matlab.ui.control.Label
        Error_x_Field_d2               matlab.ui.control.NumericEditField
        Error_x_Label_3                matlab.ui.control.Label
        thetaField_d2                  matlab.ui.control.NumericEditField
        thetaLabel_3                   matlab.ui.control.Label
        yField_d2                      matlab.ui.control.NumericEditField
        yLabel_3                       matlab.ui.control.Label
        xField_d2                      matlab.ui.control.NumericEditField
        xLabel_3                       matlab.ui.control.Label
        Drone2PoseLabel                matlab.ui.control.Label
        Error_theta_Field_d1           matlab.ui.control.NumericEditField
        Error_theta_Label_2            matlab.ui.control.Label
        Error_y_Field_d1               matlab.ui.control.NumericEditField
        Error_y_Label_2                matlab.ui.control.Label
        Error_x_Field_d1               matlab.ui.control.NumericEditField
        Error_x_Label_2                matlab.ui.control.Label
        thetaField_d1                  matlab.ui.control.NumericEditField
        thetaLabel_2                   matlab.ui.control.Label
        yField_d1                      matlab.ui.control.NumericEditField
        yLabel_2                       matlab.ui.control.Label
        xField_d1                      matlab.ui.control.NumericEditField
        xLabel_2                       matlab.ui.control.Label
        Drone1PoseLabel                matlab.ui.control.Label
        ParticleSlider_d               matlab.ui.control.Slider
        DronesSliderLabel              matlab.ui.control.Label
        ParticleEditField_d            matlab.ui.control.NumericEditField
        UWBLabel                       matlab.ui.control.Label
        MotionLabel_2                  matlab.ui.control.Label
        DronesuncertaintiesLabel       matlab.ui.control.Label
        Spinner_Q12_d                  matlab.ui.control.Spinner
        rSpinnerLabel_2                matlab.ui.control.Label
        Spinner_Q2_d                   matlab.ui.control.Spinner
        thetaSpinner_2Label_2          matlab.ui.control.Label
        Spinner_R3_d                   matlab.ui.control.Spinner
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SystematicButton               matlab.ui.control.RadioButton
        MultinomialButton              matlab.ui.control.RadioButton
        OffButton                      matlab.ui.control.RadioButton
        thetaSpinnerLabel_2            matlab.ui.control.Label
        Spinner_R12_d                  matlab.ui.control.Spinner
        ParticleEditField_t            matlab.ui.control.NumericEditField
        xySpinnerLabel_2               matlab.ui.control.Label
        SnapshotButton                 matlab.ui.control.Button
        ParticleSlider_t               matlab.ui.control.Slider
        TractorLabel                   matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        estimatedposeLabel             matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        realtrajectoryLabel            matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Drone2Label                    matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Drone1Label                    matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        TractorLabel_2                 matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        CheckBoxes                     matlab.ui.container.ButtonGroup
        Estimation_CheckBox            matlab.ui.control.CheckBox
        Measurement_CheckBox           matlab.ui.control.CheckBox
        GroundTruth_CheckBox           matlab.ui.control.CheckBox
        outliersdetectedLabel          matlab.ui.control.Label
        OutlierField                   matlab.ui.control.NumericEditField
        n_measurementsLabel            matlab.ui.control.Label
        MeasurementField               matlab.ui.control.NumericEditField
        MeasurementsLabel              matlab.ui.control.Label
        OutlierSpinner_t               matlab.ui.control.Spinner
        OutlierSwitch_t                matlab.ui.control.Switch
        TractorOutliersDetectionLabel  matlab.ui.control.Label
        Error_theta_Field_t            matlab.ui.control.NumericEditField
        Error_theta_Label              matlab.ui.control.Label
        Error_y_Field_t                matlab.ui.control.NumericEditField
        Error_y_Label                  matlab.ui.control.Label
        Error_x_Field_t                matlab.ui.control.NumericEditField
        Error_x_Label                  matlab.ui.control.Label
        TractorPoseLabel               matlab.ui.control.Label
        ParticleFiltersLabel           matlab.ui.control.Label
        thetaField_t                   matlab.ui.control.NumericEditField
        thetaLabel                     matlab.ui.control.Label
        yField_t                       matlab.ui.control.NumericEditField
        yLabel                         matlab.ui.control.Label
        xField_t                       matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        GPSLabel                       matlab.ui.control.Label
        MotionLabel                    matlab.ui.control.Label
        TractoruncertaintiesLabel      matlab.ui.control.Label
        Spinner_Q12_t                  matlab.ui.control.Spinner
        xyLabel                        matlab.ui.control.Label
        Spinner_R3_t                   matlab.ui.control.Spinner
        thetaSpinnerLabel              matlab.ui.control.Label
        Spinner_R12_t                  matlab.ui.control.Spinner
        xLabel_4                       matlab.ui.control.Label
        StartPauseButton               matlab.ui.control.Button
        SimulationAxis                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            reset_app(app);
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            global target_type 
            global n_apples
            target_type = app.TargetDropDown.Value;
            if strcmp(target_type, 'Default')
                app.AppleNumberSpinner.Editable = "off";
                app.AppleNumberSpinner.Enable = "off";
                n_apples = 15;
                app.AppleNumberSpinner.Value = n_apples;
            else
                app.AppleNumberSpinner.Editable = "on";
                app.AppleNumberSpinner.Enable = "on";
            end

        end

        % Button pushed function: StartPauseButton
        function StartPauseButtonPushed(app, event)
            global started
            global running
            if ~running
                app.StartPauseButton.Text = 'Pause';
                running = 1;
                if ~started
                    % TODO: disable possibility to change number of particles
                    % run simulation; init of the variables inside the function
                    app.ParticleSlider_t.Enable = 'off';
                    app.ParticleEditField_t.Enable = 'off';
                    app.ParticleSlider_d.Enable = 'off';
                    app.ParticleEditField_d.Enable = 'off';

                    app.TargetDropDown.Enable= 'off';
                    app.AppleNumberSpinner.Enable= 'off';
                    started = 1;
                    start_simulation(app);
                end
            else
                app.ParticleSlider_t.Enable = 'on';
                app.ParticleEditField_t.Enable = 'on';
                app.ParticleSlider_d.Enable = 'on';
                app.ParticleEditField_d.Enable = 'on';

                app.StartPauseButton.Text = 'Unpause';
                running = 0;
            end

           
        end

        % Value changed function: Spinner_R12_t
        function Spinner_R12_tValueChanged(app, event)
            global filter_t
            % update motion model covariance matrix
            filter_t.R(1,1) = app.Spinner_R12_t.Value^2;
            filter_t.R(2,2) = app.Spinner_R12_t.Value^2;

        end

        % Value changed function: Spinner_R3_t
        function Spinner_R3_tValueChanged(app, event)
            global filter_t
            % update motion model covariance matrix
            filter_t.R(3,3) = (app.Spinner_R3_t.Value / 360 * (2*pi))^2;
        end

        % Value changed function: Spinner_Q12_t
        function Spinner_Q12_tValueChanged(app, event)
            global filter_t
            % update measurement update covariance matrix
            filter_t.Q(1,1) = app.Spinner_Q12_t.Value^2;  
            filter_t.Q(2,2) = app.Spinner_Q12_t.Value^2;  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % set stop_execution flag so simulation is stopped at the end of the iteration 
            global running
            global started
            global stop_execution
            stop_execution = 1;
            
            % toggle app state variables
            running = 0;
            started = 0;
            
            % change UI 
            app.TargetDropDown.Enable = "on";
            app.AppleNumberSpinner.Enable = "on";
            app.StartPauseButton.Text = 'Start';       

            % if simulation finished without being interrupted, rename
            % button from "end" to "stop"
            if strcmp(app.StopButton.Text, 'End')
                app.StopButton.Text = 'Stop';
                app.ErrorLabel.Text = 'Error';
            end
        end

        % Value changed function: GroundTruth_CheckBox
        function GroundTruth_CheckBoxValueChanged(app, event)
            global show_ground_truth
            show_ground_truth = app.GroundTruth_CheckBox.Value;
        end

        % Value changed function: Measurement_CheckBox
        function Measurement_CheckBoxValueChanged(app, event)
            global show_measurements
            show_measurements = app.Measurement_CheckBox.Value;
        end

        % Value changed function: Estimation_CheckBox
        function Estimation_CheckBoxValueChanged(app, event)
            global show_estimation
            show_estimation = app.Estimation_CheckBox.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global running
            global started
            global stop_execution
            stop_execution = 1;
            
            % toggle app state variables
            running = 0;
            started = 0;
            
            % change UI 
            app.TargetDropDown.Enable = "on";
            app.AppleNumberSpinner.Enable = "on";
            app.StartPauseButton.Text = 'Start';       

            reset_app(app);                       
        end

        % Value changed function: OutlierSwitch_t
        function OutlierSwitch_tValueChanged(app, event)
            global filter_t
            if strcmp(app.OutlierSwitch_t.Value, 'On')
                app.OutlierSpinner_t.Visible = true;
                filter_t.lambda_psi = filter_t.default_lambda_psi;
                app.OutlierSpinner_t.Value = filter_t.lambda_psi;
            else
                app.OutlierSpinner_t.Visible = false;
                filter_t.lambda_psi = 0;
            end
        end

        % Value changed function: OutlierSpinner_t
        function OutlierSpinner_tValueChanged(app, event)
            global filter_t
            filter_t.lambda_psi = app.OutlierSpinner_t.Value;
        end

        % Callback function
        function DataAssociationSwitchValueChanged(app, event)
            global DATA_ASSOCIATION
            DATA_ASSOCIATION = app.DataAssociationSwitch.Value;
        end

        % Value changed function: ParticleSlider_t
        function ParticleSlider_tValueChanged(app, event)
            global filter_t
            filter_t.M = round(app.ParticleSlider_t.Value);
            app.ParticleEditField_t.Value = filter_t.M;
        end

        % Value changed function: GlobalTrackingSwitch
        function GlobalTrackingSwitchValueChanged(app, event)
            global global_localization
            global filter_t
            global filter_d1
            global filter_d2

            global default_lambda_psi;
            if strcmp(app.GlobalTrackingSwitch.Value, 'Global')
                global_localization = 1;
                % app.OutlierSwitch_t.Value = 'Off';
                % app.OutlierSpinner_t.Visible = false;
                % filter_t.lambda_psi = 0;
                % filter_d1.lambda_psi = 0;
                % filter_d2.lambda_psi = 0;
            else
                global_localization = 0;
                % app.OutlierSwitch_t.Value = 'On';
                % app.OutlierSpinner_t.Visible = true;
                % filter_t.lambda_psi = filter_t.default_lambda_psi;
                % filter_d1.lambda_psi = filter_d1.default_lambda_psi;
                % filter_d2.lambda_psi = filter_d2.default_lambda_psi;
                % app.OutlierSpinner_t.Value = filter_t.lambda_psi;
                % app.OutlierSpinner_d.Value = filter_d1.lambda_psi;                
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global filter_t
            global filter_d1
            global filter_d2
            
            sample_strategy = app.ButtonGroup.SelectedObject.Text;
            if strcmp(sample_strategy, 'Off')
                filter_t.resampling_mode = 0;
                filter_d1.resampling_mode = 0;
                filter_d2.resampling_mode = 0;
            elseif strcmp(sample_strategy, 'Multinomial')
                filter_t.resampling_mode = 1;
                filter_d1.resampling_mode = 1;
                filter_d2.resampling_mode = 1;
            else
                filter_t.resampling_mode = 2;
                filter_d1.resampling_mode = 2;
                filter_d2.resampling_mode = 2;            
            end
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            global t 
            global filter_t
            global filter_d1
            global filter_d2
            % create a temporary figure with axes.
            snap_fig = figure('Resize', 'off');
            snap_axes = axes(snap_fig);
            % set title
            title(snap_axes, sprintf('Simulation Time: %.1f s', round(t, 1)));
            snap_axes.Title.FontName = app.SimulationAxis.Title.FontName;
            snap_axes.Title.FontSize = app.SimulationAxis.Title.FontSize;
            % copy all SimulationAxis children, take over axes limits and aspect ratio.            
            allChildren = app.SimulationAxis.XAxis.Parent.Children;
            copyobj(allChildren, snap_axes)
            snap_axes.XLim = app.SimulationAxis.XLim;
            snap_axes.YLim = app.SimulationAxis.YLim;
            snap_axes.TickLength = app.SimulationAxis.TickLength;
            snap_axes.FontName = app.SimulationAxis.FontName;
            snap_axes.FontSize = app.SimulationAxis.FontSize;
            snap_axes.Box = 'on';
            snap_axes.XTick = app.SimulationAxis.XTick;
            snap_axes.YTick = app.SimulationAxis.YTick;
            % position axis within figure
            outerpos = snap_axes.OuterPosition;
            top_margin = snap_axes.TightInset(4); 
            ax_height = outerpos(4) - top_margin;
            snap_axes.Position = [outerpos(1) outerpos(2) outerpos(3) ax_height];
%             relative_height = snap_axes.Position(4); % relative height of axis w.r.t figure
            % adjust YLim to make space for legend
%             y_span_old = snap_axes.YLim(2) - snap_axes.YLim(1);
            relative_y_lim_max_increase = 0.20; % relative increase of y-axis maximum
            snap_axes.YLim(2) = (1+relative_y_lim_max_increase) * snap_axes.YLim(2);
%             y_span_new = snap_axes.YLim(2) - snap_axes.YLim(1);
%             relative_height_increase = (y_span_new / y_span_old) - 1; % relative increase of axis size
%             box_height_axis = relative_height_increase / (1+relative_height_increase); % percentage of axis height free for box
%             relative_box_height = box_height_axis*relative_height; % relative height of box w.r.t. figure
%             relative_box_width = 0.375;
%             box_dim = [(1-relative_box_width)/2, relative_height-relative_box_height, relative_box_width, relative_box_height*0.85];
            box_dim = [0.20,0.6,0.4,0.3];
            % print standard deviations of motion and observation model and
            % outlier detection information
            str1 = strcat('\textbf{Std Tractor Motion Model}: x:\thinspace', num2str(round(sqrt(filter_t.R(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_t.R(2, 2)), 3))); %, ' $\theta$:\thinspace', num2str(round(sqrt(filter_t.R(3, 3)), 3)));
            str2 = strcat('\textbf{Std Tractor Observation Model:} x:\thinspace', num2str(round(sqrt(filter_t.Q(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_t.Q(2, 2)), 3)));
            str3 = strcat('\textbf{Std Drone 1 Motion Model}: x:\thinspace', num2str(round(sqrt(filter_d1.R(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_d1.R(2, 2)), 3))); %, ' $\theta$:\thinspace', num2str(round(sqrt(filter_d1.R(3, 3)), 3)));
            str4 = strcat('\textbf{Std Drone 1 Observation Model:} x:\thinspace', num2str(round(sqrt(filter_d1.Q(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_d1.Q(2, 2)), 3)));
            str5 = strcat('\textbf{Std Drone 2 Motion Model}: x:\thinspace', num2str(round(sqrt(filter_d2.R(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_d2.R(2, 2)), 3))); %, ' $\theta$:\thinspace', num2str(round(sqrt(filter_d2.R(3, 3)), 3)));
            str6 = strcat('\textbf{Std Drone 2 Observation Model:} x:\thinspace', num2str(round(sqrt(filter_d2.Q(1, 1)), 3)), ' y:\thinspace', num2str(round(sqrt(filter_d2.Q(2, 2)), 3)));
%             if lambda_psi == 0
%                 str3 = '\textbf{Outlier Detection} Off';
%             else
%                 str3 = strcat('\textbf{Outlier Detection} On, $\lambda_{\psi}$:\thinspace', num2str(lambda_psi));
%             end
            str = char(str1, str2, str3, str4, str5, str6);
            % create annotation box with specified information
            box = annotation('textbox', box_dim, 'Interpreter', 'latex', 'String', str, 'FitBoxToText', 'on');
            % place new figure on top of GUI
            WinOnTop(snap_fig);
        end

        % Value changed function: Spinner_R2_t
        function Spinner_R2_tValueChanged(app, event)
            global filter_t
            filter_t.R(2,2) = app.Spinner_R2_t.Value^2;
        end

        % Value changed function: Spinner_R12_d
        function Spinner_R12_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            filter_d1.R(1,1) = app.Spinner_R12_d.Value^2;            
            filter_d1.R(2,2) = app.Spinner_R12_d.Value^2;  
            filter_d2.R(1,1) = app.Spinner_R12_d.Value^2;            
            filter_d2.R(2,2) = app.Spinner_R12_d.Value^2;             
        end

        % Value changed function: Spinner_R3_d
        function Spinner_R3_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            filter_d1.R(3,3) = app.Spinner_R3_d.Value^2; 
            filter_d2.R(3,3) = app.Spinner_R3_d.Value^2; 
        end

        % Value changed function: Spinner_Q12_d
        function Spinner_Q12_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            filter_d1.Q(1,1) = app.Spinner_Q12_d.Value^2; 
            filter_d2.Q(1,1) = app.Spinner_Q12_d.Value^2;
            filter_d1.Q(2,2) = app.Spinner_Q12_d.Value^2; 
            filter_d2.Q(2,2) = app.Spinner_Q12_d.Value^2; 
        end

        % Value changed function: Spinner_Q2_d
        function Spinner_Q2_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            filter_d1.Q(2,2) = app.Spinner_Q2_d.Value^2; 
            filter_d2.Q(2,2) = app.Spinner_Q2_d.Value^2; 
        end

        % Value changed function: ParticleEditField_t
        function ParticleEditField_tValueChanged(app, event)
            global filter_t
            filter_t.M = round(app.ParticleEditField_t.Value);
            app.ParticleSlider_t.Value = filter_t.M;
        end

        % Value changed function: ParticleSlider_d
        function ParticleSlider_dValueChanged(app, event)
            global filter_d1
            global filter_d2

            filter_d1.M = round(app.ParticleSlider_d.Value);
            filter_d2.M = round(app.ParticleSlider_d.Value);
            app.ParticleEditField_d.Value = filter_d1.M;
        end

        % Value changed function: ParticleEditField_d
        function ParticleEditField_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            
            filter_d1.M = round(app.ParticleEditField_d.Value);
            filter_d2.M = round(app.ParticleEditField_d.Value);
            app.ParticleSlider_d.Value = filter_d1.M;
        end

        % Value changed function: AppleNumberSpinner
        function AppleNumberSpinnerValueChanged(app, event)
            global n_apples
            global apples_map
            % update motion model covariance matrix
            n_apples = app.AppleNumberSpinner.Value;
            apples_map = zeros(2, n_apples);
        end

        % Value changed function: OutlierPercentageSpinner
        function OutlierPercentageSpinnerValueChanged(app, event)
            global outliers_percentage
            outliers_percentage = app.OutlierPercentageSpinner.Value;           
        end

        % Value changed function: OutlierSwitch_d
        function OutlierSwitch_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            if strcmp(app.OutlierSwitch_d.Value, 'On')
                app.OutlierSpinner_d.Visible = true;
                filter_d1.lambda_psi = filter_d1.default_lambda_psi;
                filter_d2.lambda_psi = filter_d2.default_lambda_psi;
                app.OutlierSpinner_d.Value = filter_d1.lambda_psi;
            else
                app.OutlierSpinner_d.Visible = false;
                filter_d1.lambda_psi = 0;
                filter_d2.lambda_psi = 0;
            end
        end

        % Value changed function: OutlierSpinner_d
        function OutlierSpinner_dValueChanged(app, event)
            global filter_d1
            global filter_d2
            filter_d1.lambda_psi = app.OutlierSpinner_d.Value;
            filter_d2.lambda_psi = app.OutlierSpinner_d.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ApplesPicker = uifigure('Visible', 'off');
            app.ApplesPicker.Name = 'Applied Estimation | Apples Picker Project';
            app.SimulationAxis = uiaxes(app.ApplesPicker);
            title(app.SimulationAxis, 'Simulation Time')
            app.SimulationAxis.XTick = [];
            app.SimulationAxis.YTick = [];
            app.StartPauseButton = uibutton(app.ApplesPicker, 'push');
            app.StartPauseButton.ButtonPushedFcn = createCallbackFcn(app, @StartPauseButtonPushed, true);
            app.StartPauseButton.Text = 'Start';
            app.xLabel_4 = uilabel(app.ApplesPicker);
            app.xLabel_4.Text = 'x, y';
            app.Spinner_R12_t = uispinner(app.ApplesPicker);
            app.Spinner_R12_t.Step = 0.01;
            app.Spinner_R12_t.Limits = [0.01 10];
            app.Spinner_R12_t.ValueDisplayFormat = '%.2fm';
            app.Spinner_R12_t.ValueChangedFcn = createCallbackFcn(app, @Spinner_R12_tValueChanged, true);
            app.Spinner_R12_t.Value = 0.01;
            app.thetaSpinnerLabel = uilabel(app.ApplesPicker);
            app.thetaSpinnerLabel.Text = 'theta';
            app.Spinner_R3_t = uispinner(app.ApplesPicker);
            app.Spinner_R3_t.Limits = [1 360];
            app.Spinner_R3_t.ValueDisplayFormat = '%d°';
            app.Spinner_R3_t.ValueChangedFcn = createCallbackFcn(app, @Spinner_R3_tValueChanged, true);
            app.Spinner_R3_t.Value = 1;
            app.xyLabel = uilabel(app.ApplesPicker);
            app.xyLabel.Text = 'x, y';
            app.Spinner_Q12_t = uispinner(app.ApplesPicker);
            app.Spinner_Q12_t.Step = 0.01;
            app.Spinner_Q12_t.Limits = [0.01 10];
            app.Spinner_Q12_t.ValueDisplayFormat = '%.2fm';
            app.Spinner_Q12_t.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q12_tValueChanged, true);
            app.Spinner_Q12_t.Value = 0.01;
            app.TractoruncertaintiesLabel = uilabel(app.ApplesPicker);
            app.TractoruncertaintiesLabel.Text = 'Tractor uncertainties';
            app.MotionLabel = uilabel(app.ApplesPicker);
            app.MotionLabel.Text = 'Motion ';
            app.GPSLabel = uilabel(app.ApplesPicker);
            app.GPSLabel.Text = 'GPS';
            app.xLabel = uilabel(app.ApplesPicker);
            app.xLabel.Text = 'x';
            app.xField_t = uieditfield(app.ApplesPicker, 'numeric');
            app.xField_t.ValueDisplayFormat = '%.2fm';
            app.xField_t.Editable = 'off';
            app.yLabel = uilabel(app.ApplesPicker);
            app.yLabel.Text = 'y';
            app.yField_t = uieditfield(app.ApplesPicker, 'numeric');
            app.yField_t.ValueDisplayFormat = '%.2fm';
            app.yField_t.Editable = 'off';
            app.thetaLabel = uilabel(app.ApplesPicker);
            app.thetaLabel.Text = 'theta';
            app.thetaField_t = uieditfield(app.ApplesPicker, 'numeric');
            app.thetaField_t.ValueDisplayFormat = '%d°';
            app.thetaField_t.Editable = 'off';
            app.ParticleFiltersLabel = uilabel(app.ApplesPicker);
            app.ParticleFiltersLabel.Text = 'Particle Filters';
            app.TractorPoseLabel = uilabel(app.ApplesPicker);
            app.TractorPoseLabel.Text = 'Tractor Pose';
            app.Error_x_Label = uilabel(app.ApplesPicker);
            app.Error_x_Label.Text = 'e_x';
            app.Error_x_Field_t = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_x_Field_t.ValueDisplayFormat = '%.2fm';
            app.Error_x_Field_t.Editable = 'off';
            app.Error_y_Label = uilabel(app.ApplesPicker);
            app.Error_y_Label.Text = 'e_y';
            app.Error_y_Field_t = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_y_Field_t.ValueDisplayFormat = '%.2fm';
            app.Error_y_Field_t.Editable = 'off';
            app.Error_theta_Label = uilabel(app.ApplesPicker);
            app.Error_theta_Label.Text = 'e_theta';
            app.Error_theta_Field_t = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_theta_Field_t.ValueDisplayFormat = '%d°';
            app.Error_theta_Field_t.Editable = 'off';
            app.TractorOutliersDetectionLabel = uilabel(app.ApplesPicker);
            app.TractorOutliersDetectionLabel.Text = 'Tractor Outliers Detection';
            app.OutlierSwitch_t = uiswitch(app.ApplesPicker, 'slider');
            app.OutlierSwitch_t.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitch_tValueChanged, true);
            app.OutlierSpinner_t = uispinner(app.ApplesPicker);
            app.OutlierSpinner_t.Step = 0.5;
            app.OutlierSpinner_t.Limits = [0 30];
            app.OutlierSpinner_t.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinner_tValueChanged, true);
            app.OutlierSpinner_t.Editable = 'off';
            app.OutlierSpinner_t.Value = 2;
            app.MeasurementsLabel = uilabel(app.ApplesPicker);
            app.MeasurementsLabel.Text = 'Measurements';
            app.MeasurementField = uieditfield(app.ApplesPicker, 'numeric');
            app.MeasurementField.ValueDisplayFormat = '%d';
            app.MeasurementField.Editable = 'off';
            app.n_measurementsLabel = uilabel(app.ApplesPicker);
            app.n_measurementsLabel.Text = 'number of measurements';
            app.OutlierField = uieditfield(app.ApplesPicker, 'numeric');
            app.OutlierField.ValueDisplayFormat = '%d';
            app.OutlierField.Editable = 'off';
            app.outliersdetectedLabel = uilabel(app.ApplesPicker);
            app.outliersdetectedLabel.Text = 'outliers detected';
            app.CheckBoxes = uibuttongroup(app.ApplesPicker);
            app.GroundTruth_CheckBox = uicheckbox(app.CheckBoxes);
            app.GroundTruth_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GroundTruth_CheckBoxValueChanged, true);
            app.GroundTruth_CheckBox.Text = 'show ground truth';
            app.GroundTruth_CheckBox.Value = true;
            app.Measurement_CheckBox = uicheckbox(app.CheckBoxes);
            app.Measurement_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Measurement_CheckBoxValueChanged, true);
            app.Measurement_CheckBox.Text = 'show measurements';
            app.Measurement_CheckBox.Value = true;
            app.Estimation_CheckBox = uicheckbox(app.CheckBoxes);
            app.Estimation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Estimation_CheckBoxValueChanged, true);
            app.Estimation_CheckBox.Text = 'show estimation';
            app.Estimation_CheckBox.Value = true;
            app.Panel = uipanel(app.ApplesPicker);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.TractorLabel_2 = uilabel(app.Panel);
            app.TractorLabel_2.Text = 'Tractor';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Drone1Label = uilabel(app.Panel);
            app.Drone1Label.Text = 'Drone 1';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.Drone2Label = uilabel(app.Panel);
            app.Drone2Label.Text = 'Drone 2';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.realtrajectoryLabel = uilabel(app.Panel);
            app.realtrajectoryLabel.Text = 'real trajectory';
            app.XLabel = uilabel(app.Panel);
            app.XLabel.Text = 'X';
            app.estimatedposeLabel = uilabel(app.Panel);
            app.estimatedposeLabel.Text = 'estimated pose';
            app.ResetButton = uibutton(app.ApplesPicker, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TractorLabel = uilabel(app.ApplesPicker);
            app.TractorLabel.Text = 'Tractor';
            app.ParticleSlider_t = uislider(app.ApplesPicker);
            app.ParticleSlider_t.Limits = [0 10000];
            app.ParticleSlider_t.MajorTicks = [0 5000 10000];
            app.ParticleSlider_t.MajorTickLabels = {'0', '5.000', '10.000'};
            app.ParticleSlider_t.ValueChangedFcn = createCallbackFcn(app, @ParticleSlider_tValueChanged, true);
            app.ParticleSlider_t.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.ParticleSlider_t.Value = 1000;
            app.SnapshotButton = uibutton(app.ApplesPicker, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Icon = fullfile(pathToMLAPP, 'camera_icon.png');
            app.SnapshotButton.Enable = 'off';
            app.SnapshotButton.Text = '';
            app.xySpinnerLabel_2 = uilabel(app.ApplesPicker);
            app.xySpinnerLabel_2.Text = 'x, y';
            app.ParticleEditField_t = uieditfield(app.ApplesPicker, 'numeric');
            app.ParticleEditField_t.ValueChangedFcn = createCallbackFcn(app, @ParticleEditField_tValueChanged, true);
            app.Spinner_R12_d = uispinner(app.ApplesPicker);
            app.Spinner_R12_d.Step = 0.01;
            app.Spinner_R12_d.Limits = [0.01 10];
            app.Spinner_R12_d.ValueDisplayFormat = '%.2fm';
            app.Spinner_R12_d.ValueChangedFcn = createCallbackFcn(app, @Spinner_R12_dValueChanged, true);
            app.Spinner_R12_d.Value = 0.01;
            app.thetaSpinnerLabel_2 = uilabel(app.ApplesPicker);
            app.thetaSpinnerLabel_2.Text = 'theta';
            app.ButtonGroup = uibuttongroup(app.ApplesPicker);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OffButton = uiradiobutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.MultinomialButton = uiradiobutton(app.ButtonGroup);
            app.MultinomialButton.Text = 'Multinomial';
            app.SystematicButton = uiradiobutton(app.ButtonGroup);
            app.SystematicButton.Text = 'Systematic';
            app.SystematicButton.Value = true;
            app.Spinner_R3_d = uispinner(app.ApplesPicker);
            app.Spinner_R3_d.Limits = [1 360];
            app.Spinner_R3_d.ValueDisplayFormat = '%d°';
            app.Spinner_R3_d.ValueChangedFcn = createCallbackFcn(app, @Spinner_R3_dValueChanged, true);
            app.Spinner_R3_d.Value = 1;
            app.thetaSpinner_2Label_2 = uilabel(app.ApplesPicker);
            app.thetaSpinner_2Label_2.Text = 'y';
            app.Spinner_Q2_d = uispinner(app.ApplesPicker);
            app.Spinner_Q2_d.Limits = [1 360];
            app.Spinner_Q2_d.ValueDisplayFormat = '%d°';
            app.Spinner_Q2_d.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q2_dValueChanged, true);
            app.Spinner_Q2_d.Value = 1;
            app.rSpinnerLabel_2 = uilabel(app.ApplesPicker);
            app.rSpinnerLabel_2.Text = 'x, y';
            app.Spinner_Q12_d = uispinner(app.ApplesPicker);
            app.Spinner_Q12_d.Step = 0.01;
            app.Spinner_Q12_d.Limits = [0.01 10];
            app.Spinner_Q12_d.ValueDisplayFormat = '%.2fm';
            app.Spinner_Q12_d.ValueChangedFcn = createCallbackFcn(app, @Spinner_Q12_dValueChanged, true);
            app.Spinner_Q12_d.Value = 0.01;
            app.DronesuncertaintiesLabel = uilabel(app.ApplesPicker);
            app.DronesuncertaintiesLabel.Text = 'Drones uncertainties';
            app.MotionLabel_2 = uilabel(app.ApplesPicker);
            app.MotionLabel_2.Text = 'Motion ';
            app.UWBLabel = uilabel(app.ApplesPicker);
            app.UWBLabel.Text = 'UWB';
            app.ParticleEditField_d = uieditfield(app.ApplesPicker, 'numeric');
            app.ParticleEditField_d.ValueChangedFcn = createCallbackFcn(app, @ParticleEditField_dValueChanged, true);
            app.DronesSliderLabel = uilabel(app.ApplesPicker);
            app.DronesSliderLabel.Text = 'Drones';
            app.ParticleSlider_d = uislider(app.ApplesPicker);
            app.ParticleSlider_d.Limits = [0 10000];
            app.ParticleSlider_d.MajorTicks = [0 5000 10000];
            app.ParticleSlider_d.MajorTickLabels = {'0', '5.000', '10.000'};
            app.ParticleSlider_d.ValueChangedFcn = createCallbackFcn(app, @ParticleSlider_dValueChanged, true);
            app.ParticleSlider_d.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.ParticleSlider_d.Value = 1000;
            app.Drone1PoseLabel = uilabel(app.ApplesPicker);
            app.Drone1PoseLabel.Text = 'Drone 1 Pose';
            app.xLabel_2 = uilabel(app.ApplesPicker);
            app.xLabel_2.Text = 'x';
            app.xField_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.xField_d1.ValueDisplayFormat = '%.2fm';
            app.xField_d1.Editable = 'off';
            app.yLabel_2 = uilabel(app.ApplesPicker);
            app.yLabel_2.Text = 'y';
            app.yField_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.yField_d1.ValueDisplayFormat = '%.2fm';
            app.yField_d1.Editable = 'off';
            app.thetaLabel_2 = uilabel(app.ApplesPicker);
            app.thetaLabel_2.Text = 'theta';
            app.thetaField_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.thetaField_d1.ValueDisplayFormat = '%d°';
            app.thetaField_d1.Editable = 'off';
            app.Error_x_Label_2 = uilabel(app.ApplesPicker);
            app.Error_x_Label_2.Text = 'e_x';
            app.Error_x_Field_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_x_Field_d1.ValueDisplayFormat = '%.2fm';
            app.Error_x_Field_d1.Editable = 'off';
            app.Error_y_Label_2 = uilabel(app.ApplesPicker);
            app.Error_y_Label_2.Text = 'e_y';
            app.Error_y_Field_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_y_Field_d1.ValueDisplayFormat = '%.2fm';
            app.Error_y_Field_d1.Editable = 'off';
            app.Error_theta_Label_2 = uilabel(app.ApplesPicker);
            app.Error_theta_Label_2.Text = 'e_theta';
            app.Error_theta_Field_d1 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_theta_Field_d1.ValueDisplayFormat = '%d°';
            app.Error_theta_Field_d1.Editable = 'off';
            app.Drone2PoseLabel = uilabel(app.ApplesPicker);
            app.Drone2PoseLabel.Text = 'Drone 2 Pose';
            app.xLabel_3 = uilabel(app.ApplesPicker);
            app.xLabel_3.Text = 'x';
            app.xField_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.xField_d2.ValueDisplayFormat = '%.2fm';
            app.xField_d2.Editable = 'off';
            app.yLabel_3 = uilabel(app.ApplesPicker);
            app.yLabel_3.Text = 'y';
            app.yField_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.yField_d2.ValueDisplayFormat = '%.2fm';
            app.yField_d2.Editable = 'off';
            app.thetaLabel_3 = uilabel(app.ApplesPicker);
            app.thetaLabel_3.Text = 'theta';
            app.thetaField_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.thetaField_d2.ValueDisplayFormat = '%d°';
            app.thetaField_d2.Editable = 'off';
            app.Error_x_Label_3 = uilabel(app.ApplesPicker);
            app.Error_x_Label_3.Text = 'e_x';
            app.Error_x_Field_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_x_Field_d2.ValueDisplayFormat = '%.2fm';
            app.Error_x_Field_d2.Editable = 'off';
            app.Error_y_Label_3 = uilabel(app.ApplesPicker);
            app.Error_y_Label_3.Text = 'e_y';
            app.Error_y_Field_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_y_Field_d2.ValueDisplayFormat = '%.2fm';
            app.Error_y_Field_d2.Editable = 'off';
            app.Error_theta_Label_3 = uilabel(app.ApplesPicker);
            app.Error_theta_Label_3.Text = 'e_theta';
            app.Error_theta_Field_d2 = uieditfield(app.ApplesPicker, 'numeric');
            app.Error_theta_Field_d2.ValueDisplayFormat = '%d°';
            app.Error_theta_Field_d2.Editable = 'off';
            app.xySpinnerLabel_3 = uilabel(app.ApplesPicker);
            app.xySpinnerLabel_3.Text = 'y';
            app.Spinner_R2_t = uispinner(app.ApplesPicker);
            app.Spinner_R2_t.Step = 0.01;
            app.Spinner_R2_t.Limits = [0.01 10];
            app.Spinner_R2_t.ValueDisplayFormat = '%.2fm';
            app.Spinner_R2_t.ValueChangedFcn = createCallbackFcn(app, @Spinner_R2_tValueChanged, true);
            app.Spinner_R2_t.Value = 0.01;
            app.ResamplingLabel = uilabel(app.ApplesPicker);
            app.ResamplingLabel.Text = 'Resampling';
            app.OutlierSpinner_d = uispinner(app.ApplesPicker);
            app.OutlierSpinner_d.Step = 0.5;
            app.OutlierSpinner_d.Limits = [0 30];
            app.OutlierSpinner_d.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinner_dValueChanged, true);
            app.OutlierSpinner_d.Editable = 'off';
            app.OutlierSpinner_d.Value = 2;
            app.OutlierDetectionLabel_2 = uilabel(app.ApplesPicker);
            app.OutlierDetectionLabel_2.Text = 'Drones Outliers Detection';
            app.OutlierSwitch_d = uiswitch(app.ApplesPicker, 'slider');
            app.OutlierSwitch_d.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitch_dValueChanged, true);
            app.StopButton = uibutton(app.ApplesPicker, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GlobalTrackingSwitch = uiswitch(app.ApplesPicker, 'slider');
            app.GlobalTrackingSwitch.Items = {'Global', 'Tracking'};
            app.GlobalTrackingSwitch.ValueChangedFcn = createCallbackFcn(app, @GlobalTrackingSwitchValueChanged, true);
            app.GlobalTrackingSwitch.Value = 'Global';
            app.OutlierDetectionLabel_5 = uilabel(app.ApplesPicker);
            app.OutlierDetectionLabel_5.Text = 'Outliers Percentage';
            app.OutlierDetectionLabel_6 = uilabel(app.ApplesPicker);
            app.OutlierDetectionLabel_6.Text = 'Problem settings';
            app.OutlierPercentageSpinner = uispinner(app.ApplesPicker);
            app.OutlierPercentageSpinner.Step = 25;
            app.OutlierPercentageSpinner.Limits = [0 50];
            app.OutlierPercentageSpinner.ValueChangedFcn = createCallbackFcn(app, @OutlierPercentageSpinnerValueChanged, true);
            app.OutlierDetectionLabel_7 = uilabel(app.ApplesPicker);
            app.OutlierDetectionLabel_7.Text = 'Targets type and number';
            app.TargetDropDown = uidropdown(app.ApplesPicker);
            app.TargetDropDown.Items = {'Default', 'Random'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'Default';
            app.AppleNumberSpinner = uispinner(app.ApplesPicker);
            app.AppleNumberSpinner.Limits = [0 100];
            app.AppleNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @AppleNumberSpinnerValueChanged, true);
            app.AppleNumberSpinner.Editable = 'off';
            app.AppleNumberSpinner.Enable = 'off';
            app.AppleNumberSpinner.Value = 15;
            app.ApplespickerLabel = uilabel(app.ApplesPicker);
            app.ApplespickerLabel.Text = 'Apples picker';
            % Show the figure after all components are created
            app.ApplesPicker.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcl_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApplesPicker)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplesPicker)
        end
    end
end